classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        ImportItem                      matlab.ui.container.Menu
        ExportItem                      matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        VisualisationPanel              matlab.ui.container.Panel
        Button4                         matlab.ui.control.Button
        Button3                         matlab.ui.control.Button
        Button2                         matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        OptionsdesimulationPanel        matlab.ui.container.Panel
        CoeffdinertiedugalvoEditField   matlab.ui.control.NumericEditField
        CoeffdinertiedugalvoEditFieldLabel  matlab.ui.control.Label
        CoeffdefrottementdugalvoEditField  matlab.ui.control.NumericEditField
        CoeffdefrottementdugalvoLabel   matlab.ui.control.Label
        InsrerlecodebarresEditField     matlab.ui.control.NumericEditField
        InsrerlecodebarresLabel         matlab.ui.control.Label
        Phi_cEditField                  matlab.ui.control.NumericEditField
        Phi_cLabel                      matlab.ui.control.Label
        Alpha_cEditField                matlab.ui.control.NumericEditField
        Alpha_cLabel                    matlab.ui.control.Label
        DistancexducodebarrescmEditField  matlab.ui.control.NumericEditField
        DistancexducodebarrescmLabel    matlab.ui.control.Label
        DistanceyducodebarrescmEditField  matlab.ui.control.NumericEditField
        DistanceyducodebarrescmLabel    matlab.ui.control.Label
        DmarrerlasimulationButton       matlab.ui.control.Button
        TempsdesimulationsSlider        matlab.ui.control.Slider
        TempsdesimulationsSliderLabel   matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        Tab2                            matlab.ui.container.Tab
        TotaldelafactureEditField       matlab.ui.control.NumericEditField
        TotaldelafactureEditFieldLabel  matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
    end

    
    properties (Access = private)
        variables = {'dx_c', 'dy_c', 'alpha_c', 'phi_c', 'barres', 'inertie_galvo', 'frottement_galvo'}
    end
    
    methods (Access = private)
        
        function WriteVarsToWorkspace(app,ws)
            assignin(ws, "dx_c",app.DistancexducodebarrescmEditField.Value);
            assignin(ws, "dy_c",app.DistanceyducodebarrescmEditField.Value);
            assignin(ws, "alpha_c",app.Alpha_cEditField.Value);
            assignin(ws, "phi_c",app.Phi_cEditField.Value);
            assignin(ws, "barres",app.InsrerlecodebarresEditField.Value);
            assignin(ws, "inertie_galvo",app.CoeffdinertiedugalvoEditField.Value);
            assignin(ws, "frottement_galvo",app.CoeffdefrottementdugalvoEditField.Value);
        end
        
        function ReadVarsFromNamespace(app,ns)
            app.DistancexducodebarrescmEditField.Value = ns.dx_c;
            app.DistanceyducodebarrescmEditField.Value = ns.dy_c;
            app.Alpha_cEditField.Value = ns.alpha_c;
            app.Phi_cEditField.Value = ns.phi_c;
            app.InsrerlecodebarresEditField.Value = ns.barres;
            app.CoeffdinertiedugalvoEditField.Value = ns.inertie_galvo;
            app.CoeffdefrottementdugalvoEditField.Value = ns.frottement_galvo;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DmarrerlasimulationButton
        function DmarrerlasimulationButtonPushed(app, event)
            WriteVarsToWorkspace(app, 'base');
            simOut=sim("Frame.slx", "StopTime",num2str(app.TempsdesimulationsSlider.Value));
            code=simOut.code;
            disp(code)
            code=transpose(code);
            for i=1:(100)
                code=[code;code(1,:)];
            end
            for i=200:300:2000
                if i==2000
                    break
                end
                code1= code(:,i:i+300);
            pas=((i-200)/300)+1;
               name = ['code' num2str(pas) '.png'];
                saveas(imshow(code1),name);
            I = imread(name);
            msg = readBarcode(I);
            
            if strlength(msg)==12
                break
            end
            end
        display(msg)
        end

        % Menu selected function: ExportItem
        function ExportItemMenuSelected(app, event)
            [file,path] = uiputfile('*.mat');
            WriteVarsToWorkspace(app, 'caller');
            filepath = fullfile(path,file);
            save(filepath, app.variables{:});
        end

        % Menu selected function: ImportItem
        function ImportItemMenuSelected(app, event)
            [file,path] = uigetfile('*.mat');
            if ~isequal(file,0)
                filepath = fullfile(path,file);
                ns = load(filepath, app.variables{:});
                ReadVarsFromNamespace(app,ns);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ImportItem = uimenu(app.FileMenu);
            app.ImportItem.MenuSelectedFcn = createCallbackFcn(app, @ImportItemMenuSelected, true);
            app.ImportItem.Text = 'Importer un fichier de configuration';
            app.ExportItem = uimenu(app.FileMenu);
            app.ExportItem.MenuSelectedFcn = createCallbackFcn(app, @ExportItemMenuSelected, true);
            app.ExportItem.Text = 'Exporter la configuration courante';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Image = uiimage(app.Tab);
            app.Image.ImageSource = 'ULAVAL.png';
            app.TempsdesimulationsSliderLabel = uilabel(app.Tab);
            app.TempsdesimulationsSliderLabel.Text = 'Temps de simulation(s)';
            app.TempsdesimulationsSlider = uislider(app.Tab);
            app.TempsdesimulationsSlider.Limits = [5 100];
            app.TempsdesimulationsSlider.Value = 5;
            app.DmarrerlasimulationButton = uibutton(app.Tab, 'push');
            app.DmarrerlasimulationButton.ButtonPushedFcn = createCallbackFcn(app, @DmarrerlasimulationButtonPushed, true);
            app.DmarrerlasimulationButton.Text = 'Démarrer la simulation';
            app.OptionsdesimulationPanel = uipanel(app.Tab);
            app.OptionsdesimulationPanel.Title = 'Options de simulation';
            app.DistanceyducodebarrescmLabel = uilabel(app.OptionsdesimulationPanel);
            app.DistanceyducodebarrescmLabel.Text = 'Distance y du code-barres (cm) :';
            app.DistanceyducodebarrescmEditField = uieditfield(app.OptionsdesimulationPanel, 'numeric');
            app.DistancexducodebarrescmLabel = uilabel(app.OptionsdesimulationPanel);
            app.DistancexducodebarrescmLabel.Text = 'Distance x du code-barres (cm) :';
            app.DistancexducodebarrescmEditField = uieditfield(app.OptionsdesimulationPanel, 'numeric');
            app.Alpha_cLabel = uilabel(app.OptionsdesimulationPanel);
            app.Alpha_cLabel.Text = 'Alpha_c :';
            app.Alpha_cEditField = uieditfield(app.OptionsdesimulationPanel, 'numeric');
            app.Phi_cLabel = uilabel(app.OptionsdesimulationPanel);
            app.Phi_cLabel.Text = 'Phi_c :';
            app.Phi_cEditField = uieditfield(app.OptionsdesimulationPanel, 'numeric');
            app.InsrerlecodebarresLabel = uilabel(app.OptionsdesimulationPanel);
            app.InsrerlecodebarresLabel.Text = 'Insérer le code-barres :';
            app.InsrerlecodebarresEditField = uieditfield(app.OptionsdesimulationPanel, 'numeric');
            app.CoeffdefrottementdugalvoLabel = uilabel(app.OptionsdesimulationPanel);
            app.CoeffdefrottementdugalvoLabel.Text = 'Coeff. de frottement du galvo :';
            app.CoeffdefrottementdugalvoEditField = uieditfield(app.OptionsdesimulationPanel, 'numeric');
            app.CoeffdinertiedugalvoEditFieldLabel = uilabel(app.OptionsdesimulationPanel);
            app.CoeffdinertiedugalvoEditFieldLabel.Text = 'Coeff. d''inertie du galvo :';
            app.CoeffdinertiedugalvoEditField = uieditfield(app.OptionsdesimulationPanel, 'numeric');
            app.VisualisationPanel = uipanel(app.Tab);
            app.VisualisationPanel.Title = 'Visualisation';
            app.Button = uibutton(app.VisualisationPanel, 'push');
            app.Button2 = uibutton(app.VisualisationPanel, 'push');
            app.Button2.Text = 'Button2';
            app.Button3 = uibutton(app.VisualisationPanel, 'push');
            app.Button3.Text = 'Button3';
            app.Button4 = uibutton(app.VisualisationPanel, 'push');
            app.Button4.Text = 'Button4';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.UITable = uitable(app.Tab2);
            app.UITable.ColumnName = {'Nom de l''article'; 'Prix'};
            app.UITable.RowName = {};
            app.TotaldelafactureEditFieldLabel = uilabel(app.Tab2);
            app.TotaldelafactureEditFieldLabel.Text = 'Total de la facture';
            app.TotaldelafactureEditField = uieditfield(app.Tab2, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end